classdef kisilik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_3        matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        TahminLabel    matlab.ui.control.Label
        TAHMNETButton  matlab.ui.control.Button
        AButton        matlab.ui.control.Button
        Image          matlab.ui.control.Image
    end


properties (Access = private)
       filepath
       filename
        net % neural network
       labels1 
       imdGlobal
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net = load('kisilikcnn.mat','trainedNetwork_1');
           
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)

    [app.filename, app.filepath] = uigetfile({'*.jpg'},'File Selector');
     imagefile = fullfile(app.filepath, app.filename);
     imd = imread(imagefile);
     imd = imresize(imd,[227 227]);
     app.imdGlobal = imd;
     app.Image.ImageSource = imd;
    
        end

        % Button pushed function: TAHMNETButton
        function TAHMNETButtonPushed(app, event)
          
         [layers,scores] = classify(app.net.trainedNetwork_1,app.imdGlobal);
         
          app.Label.Text = layers;
          savoryRate = scores(1) *100;
          unSavoryRate = scores(2) * 100;
          str = "Savory : %" + savoryRate;
          str2 = "Unsavory : %" + unSavoryRate;
          app.Label_2.Text = str;
          app.Label_3.Text = str2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'AÇ';
            app.TAHMNETButton = uibutton(app.UIFigure, 'push');
            app.TAHMNETButton.ButtonPushedFcn = createCallbackFcn(app, @TAHMNETButtonPushed, true);
            app.TAHMNETButton.Text = 'TAHMİN ET';
            app.TahminLabel = uilabel(app.UIFigure);
            app.TahminLabel.Text = 'Tahmin : ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kisilik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end